classdef MonitorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HRLabel               matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        BPMLabel              matlab.ui.control.Label
        HR_value_label        matlab.ui.control.Label
        SpO2Label             matlab.ui.control.Label
        PercentageLabel       matlab.ui.control.Label
        SpO2_value_label      matlab.ui.control.Label
        TempLabel             matlab.ui.control.Label
        CLabel                matlab.ui.control.Label
        Temp_value_label      matlab.ui.control.Label
        StartStopPanel        matlab.ui.container.Panel
        StartButton           matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        AlarmThresholdsPanel  matlab.ui.container.Panel
        SpO2Panel             matlab.ui.container.Panel
        MinLabel_2            matlab.ui.control.Label
        MaxLabel_2            matlab.ui.control.Label
        MinEditLimitSpO2      matlab.ui.control.Label
        MaxEditLimitSpO2      matlab.ui.control.Label
        HRPanel               matlab.ui.container.Panel
        MinLabel              matlab.ui.control.Label
        MaxLabel              matlab.ui.control.Label
        MinEditLimitHR        matlab.ui.control.Label
        MaxEditLimitHR        matlab.ui.control.Label
        TempPanel             matlab.ui.container.Panel
        MinLabel_3            matlab.ui.control.Label
        MaxLabel_3            matlab.ui.control.Label
        MinEditLimitTemp      matlab.ui.control.Label
        MaxEditLimitTemp      matlab.ui.control.Label
        EditButton            matlab.ui.control.Button
        MutealarmButton       matlab.ui.control.Button
        Image_AlarmOn         matlab.ui.control.Image
        Image_AlarmPaused     matlab.ui.control.Image
    end

    
    properties (Access = private)
        SaveDialog                   % Dialog box app
        DataTable                    % table for data
        CurrentHR
        CurrentSpO2
        CurrentTemp
        AlarmState 
        MonitorState                 % State for monitor 
        DataTimer                    % Timer for reading data from arduino
        S_connection                 % Serial object for dataprocessing
        progressbar                  % Feedback to user
        timer_alarmAudio             % timer for playing alarm audio
        timer_alarmFlash             % timer for flashing label when alarming 
        Alarm_HR                     % Alarm state for HR 
        Alarm_SpO2                   % Alarm state for SpO2
        Alarm_temp                   % Alarm state for temp                      
        AlarmAudioMuted = false      % used when user press the mute button
    end
    
    properties (Access = public)
        MeasureValueLabel = cell(3,1); % Property for which label there should flash
        player                         % player for the alarm sound
    end


    methods (Access = public) % public funktion som kan kaldes fra andre Apps
        
        function Test_update(app, x) % skal denne slettes??
            app.MinEditLimitHR.Text = x;
        end
    end
    
    methods (Access = private)
        
        function CheckAlarmValues(app)
            app.Label = cell(3,1); % nulstilles Label
            
            % OBS på om vi bruger current propertie?? ellers skal der tjekkes på hvilke værdier der vises på GUI
            
            
             % Checks all 3 mesasure values
            if app.CurrentHR > app.MaxEditLimitHR || app.CurrentHR < app.MinEditLimitHR
                app.Alarm_HR = true;
                app.MeasureValueLabel{1} = app.HR_value_label;   
            else
                app.Alarm_HR = false;
            end
            if app.CurrentSpO2 > app.MaxEditLimitSpO2 || app.CurrentSpO2 < app.MinEditLimitSpO2
                app.Alarm_SpO2 = true;
                app.MeasureValueLabel{2} = app.SpO2_value_label; 
            else
                app.Alarm_SpO2 = false;
            end
            if app.CurrentTemp > app.MaxEditLimitTemp || app.CurrentTemp < app.MaxEditLimitTemp
                app.Alarm_temp = true;
                app.MeasureValueLabel{3} = app.Temp_value_label; 
            else
                app.Alarm_temp = false;
            end
            
            % if just one measure value is critical alarm should start
            if app.Alarm_HR|| app.Alarm_SpO2 || app.Alarm_temp
                StartAlarm(app);
            end
            % if just one of the measure values is OK, stop alarm is
            % called, so the label can be updated
            if ~app.Alarm_HR || ~app.Alarm_SpO2|| ~app.Alarm_temp
                StopAlarm(app);
            end
        end

        function StartAlarm(app)
            
            if strcmp(app.timer_alarmAudio.Running, 'off') % if timer for alarm audio is off                
                if ~app.AlarmAudioMuted % run if the alarm is not muted
                    app.Image_AlarmOn.Visible = true; % show symbol                    
                    start(app.timer_alarmAudio);
                    
                    % Show button for mute alarm
                    app.MutealarmButton.Visible = true; 
                    app.MutealarmButton.Enable = true;
                    
                   % MANGLER -> indsæt værdier i tabel
                end
            end
            % update the alarm flash
            stop(app.timer_alarmFlash);
            app.timer_alarmFlash.TimerFcn = {@flashAlarmLabel, app, app.Label};
            start(app.timer_alarmFlash);
                   
        end       
        
        function StopAlarm(app)
            
            % only runs if the alarm is on
            if strcmp(app.timer_alarmAudio.Running, 'on') % If alarm audio is on
                
                % stops only the alarm audio and hides the symbol if no alarmStates is true
                if ~app.Alarm_HR && ~app.Alarm_SpO2 && ~app.Alarm_temp
                    app.Image_AlarmOn.Visible = false;
                    app.Image_AlarmPaused.Visible = false;
                    stop(app.timer_alarmAudio);
                    app.MutealarmButton.Enable = false;
                    app.MutealarmButton.Visible = false;
                    stop(app.timer_alarmFlash);
                else
                    % updates the alarm flash
                    stop(app.timer_alarmFlash);
                    app.timer_alarmFlash.TimerFcn = {@flashAlarmLabel, app, app.Label};
                    start(app.timer_alarmFlash);
                end
                
                % Set labels back to the right color
                if ~app.Alarm_HR
                    app.HR_value_label.FontColor = "0.39,0.83,0.07";
                    app.HR_value_label.Visible = 'on';
                end
                if ~app.Alarm_SpO2
                    app.SpO2_value_label.FontColor = "0.07,0.62,1.00";
                    app.SpO2_value_label.Visible = 'on';
                end
                if ~app.Alarm_temp
                    app.Temp_value_label.FontColor = "1.00,1.00,0.00";
                    app.Temp_value_label.Visible = 'on';
                end
            end
            
            
        end
        
%         function SetUpConnection(app)
%             % Setup serial connection
%             comPort = 'COM3';
%             app.S_connection = serial(comPort,'BaudRate',115200);
%             
%             % Open connection
%             fopen(app.S_connection);
%             disp('Serial connection is open...')
%         end
%         
%         function StopMonitor(app)
%             fclose(app.S_connection);
%             delete(app.S_connection);
%             disp('Serial Connection is closed')
%         end
%         
%         function GetPulseSpO2(app,~,~)
%             processRawData(app.S_connection,app.HR_value_label,app.SpO2_value_label);
%             close(app.progressbar)
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             % Creating timer for getting measurement values
%             app.DataTimer = timer(...
%                 'ExecutionMode', 'fixedRate', ...
%                 "Period", .2, ... % OBS!!!! Change value to how often the gui should update
%                 "BusyMode", "queue", ...
%                 "TimerFcn", @app.GetPulseSpO2);
            
            % Create timer class for flashing label             
             app.timer_alarmFlash = timer(... 
                'ExecutionMode', 'fixedRate', ...
                "Period", 0.50, ...
                "BusyMode", "queue", ...
                "TimerFcn", {@flashAlarmLabel, app, app.MeasureValueLabel}); %both app and app.Label must be inputs for the function has access to app.Label             
            
            % load melody for alarm audio
            [y, Fs] = audioread('alarmMedPause.wav');
            app.player = audioplayer(y, Fs);
            
            % Create timer class for playing alarm audio 
            app.timer_alarmAudio = timer(... 
                'ExecutionMode', 'fixedRate', ...
                "Period", 0.1, ...
                "BusyMode", "queue", ...
                "TimerFcn", {@audioAlarm, app, app.player});
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Her skal stå kode for når der trykkes på gem knappen
            
            %Test for at Gemme noget random kode ned i tabel
            % app.DataTable = "d";
            
            % Åbner SaveDialogApp
            app.SaveDialog = SaveDialogApp(app, app.DataTable);
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.StartButton.Text == "Start"
                app.StartButton.Text = "Stop";
                app.StartButton.BackgroundColor = "0.94,0.09,0.09";
                app.MonitorState = "Started";
%                 app.progressbar = uiprogressdlg(app.UIFigure,'Title','Start monitor',...
%                     'Indeterminate','on');
%                 app.SetUpConnection();
%                 start(app.DataTimer);
            else
                app.StartButton.Text = "Start";
                app.StartButton.BackgroundColor = "0.47,0.67,0.19";
                app.MonitorState = "Stopped";
%                 stop(app.DataTimer);
%                 app.StopMonitor();
            end
    
            
        end

        % Button pushed function: MutealarmButton
        function MutealarmButtonPushed(app, event)
            
            app.AlarmAudioMuted = true;
            app.MutealarmButton.Enable = false; 
            stop(app.timer_alarmAudio);  
            app.Image_AlarmOn.Visible = false;
            app.Image_AlarmPaused.Visible = true;
            pause(8);   
            %if timer for alarm audio is off -> if there in the
            %mean time has ben registered an alarm for a measure value, 
            if strcmp(app.timer_alarmAudio.Running, 'off') 
            start(app.timer_alarmAudio); 
            end
            app.Image_AlarmOn.Visible = true;
            app.Image_AlarmPaused.Visible = false;
            app.MutealarmButton.Enable = true; 
            app.AlarmAudioMuted = false;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all apps
            delete(app.SaveDialog)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = {'HR'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'image_processing20200511-27178-yzw8o8.png';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.HR_value_label = uilabel(app.UIFigure);
            app.HR_value_label.Text = '0';
            app.SpO2Label = uilabel(app.UIFigure);
            app.SpO2Label.Text = 'SpO2';
            app.PercentageLabel = uilabel(app.UIFigure);
            app.PercentageLabel.Text = '%';
            app.SpO2_value_label = uilabel(app.UIFigure);
            app.SpO2_value_label.Text = '0';
            app.TempLabel = uilabel(app.UIFigure);
            app.TempLabel.Text = 'Temp';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = '°C';
            app.Temp_value_label = uilabel(app.UIFigure);
            app.Temp_value_label.Text = '0';
            app.StartStopPanel = uipanel(app.UIFigure);
            app.StartStopPanel.Title = 'Start/Stop';
            app.StartButton = uibutton(app.StartStopPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.StartStopPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save-file.png';
            app.SaveButton.Text = 'Save';
            app.AlarmThresholdsPanel = uipanel(app.UIFigure);
            app.AlarmThresholdsPanel.Title = 'Alarm Thresholds';
            app.SpO2Panel = uipanel(app.AlarmThresholdsPanel);
            app.SpO2Panel.Title = 'SpO2';
            app.MinLabel_2 = uilabel(app.SpO2Panel);
            app.MinLabel_2.Text = 'Min:';
            app.MaxLabel_2 = uilabel(app.SpO2Panel);
            app.MaxLabel_2.Text = 'Max:';
            app.MinEditLimitSpO2 = uilabel(app.SpO2Panel);
            app.MinEditLimitSpO2.Text = '89';
            app.MaxEditLimitSpO2 = uilabel(app.SpO2Panel);
            app.MaxEditLimitSpO2.Text = '96';
            app.HRPanel = uipanel(app.AlarmThresholdsPanel);
            app.HRPanel.Title = 'HR';
            app.MinLabel = uilabel(app.HRPanel);
            app.MinLabel.Text = 'Min:';
            app.MaxLabel = uilabel(app.HRPanel);
            app.MaxLabel.Text = 'Max:';
            app.MinEditLimitHR = uilabel(app.HRPanel);
            app.MinEditLimitHR.Text = '100';
            app.MaxEditLimitHR = uilabel(app.HRPanel);
            app.MaxEditLimitHR.Text = '180';
            app.TempPanel = uipanel(app.AlarmThresholdsPanel);
            app.TempPanel.Title = 'Temp';
            app.MinLabel_3 = uilabel(app.TempPanel);
            app.MinLabel_3.Text = 'Min:';
            app.MaxLabel_3 = uilabel(app.TempPanel);
            app.MaxLabel_3.Text = 'Max:';
            app.MinEditLimitTemp = uilabel(app.TempPanel);
            app.MinEditLimitTemp.Text = '36,5';
            app.MaxEditLimitTemp = uilabel(app.TempPanel);
            app.MaxEditLimitTemp.Text = '37,5';
            app.EditButton = uibutton(app.AlarmThresholdsPanel, 'push');
            app.EditButton.Text = 'Edit';
            app.MutealarmButton = uibutton(app.UIFigure, 'push');
            app.MutealarmButton.ButtonPushedFcn = createCallbackFcn(app, @MutealarmButtonPushed, true);
            app.MutealarmButton.Enable = 'off';
            app.MutealarmButton.Visible = 'off';
            app.MutealarmButton.Text = 'MUTE ALARM';
            app.Image_AlarmOn = uiimage(app.UIFigure);
            app.Image_AlarmOn.Visible = 'off';
            app.Image_AlarmOn.ImageSource = 'Alarm_billede2.PNG';
            app.Image_AlarmPaused = uiimage(app.UIFigure);
            app.Image_AlarmPaused.Visible = 'off';
            app.Image_AlarmPaused.ImageSource = 'Alarm_billede1.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonitorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end