classdef SaveDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxesHR            matlab.ui.control.UIAxes
        UIAxesSPO2          matlab.ui.control.UIAxes
        UIAxesTemp          matlab.ui.control.UIAxes
        AlarmeringerPanel   matlab.ui.container.Panel
        DatoogtidPanel      matlab.ui.container.Panel
        TidEditFieldLabel   matlab.ui.control.Label
        TidEditField        matlab.ui.control.EditField
        DatoEditFieldLabel  matlab.ui.control.Label
        DatoEditField       matlab.ui.control.EditField
        IndtastpatientensCPRnummerogtrykOKPanel  matlab.ui.container.Panel
        xxxxxxxxxxLabel     matlab.ui.control.Label
        CPROKButton         matlab.ui.control.Button
        CPRLabel            matlab.ui.control.Label
        CPREditField        matlab.ui.control.EditField
        GemAnnullerPanel    matlab.ui.container.Panel
        GemButton           matlab.ui.control.Button
        AnnullerButton      matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        MonitorApp   % Main app objekt
        DataToSave
        
        Time %Current time
        Date %Current date
        CurrentCPR  %CPR number of current patient
    end
    
    methods (Access = private)
        
        function PresentData(app)
            %%%%%%%%%%%%%%Tabeller laves%%%%%%%%%%%skal slettes når der er styr på flowet
            MeasurementTable = table([1;2;3;4;5],[99;97;96;98;95],[120;140;110;120;130],[37;38;39;37;36]);
            MeasurementTable.Properties.VariableNames = {'Time','O2sat','Pulse','Temp'};
            
            %Værdier sættes
            y1 = MeasurementTable.Pulse;
            y2 = MeasurementTable.O2sat;
            y3 = MeasurementTable.Temp;
            
            %Plot data
            plot(app.UIAxesHR,y1,'*-r')
            title(app.UIAxesHR,'HR',"Color",'w')
            xlabel(app.UIAxesHR,'Time','Color','w')
            ylabel(app.UIAxesHR,'BPM','Color','w')
            
            plot(app.UIAxesSPO2,y2,'*-b')
            title(app.UIAxesSPO2,'SPO2',"Color",'w')
            xlabel(app.UIAxesSPO2,'Time','Color','w')
            ylabel(app.UIAxesSPO2,'%','Color','w')
            
            plot(app.UIAxesTemp,y3,'*-g')
            title(app.UIAxesTemp,'Temp',"Color",'w')
            xlabel(app.UIAxesTemp,'Time','Color','w')
            ylabel(app.UIAxesTemp,'*C','Color','w')
            
            %Set current time on GUI
            app.Time = datestr(now, 'HH:MM');
            app.TidEditField.Value = app.Time;
            
            %Set current date on GUI
            app.Date = datestr(now, 'dd/mm/yyyy');
            app.DatoEditField.Value = app.Date;
        end
        
        function ValidateCPR(app)
            %Tjek om CPR-nummeret består af mere end tal - str2double returnerer NaN hvis string indeholder symboler eller bogstaver
            checkCPR = str2double(app.CPREditField.Value);
            if isnan(checkCPR)
                msgbox('Invalid CPR-number - It should only contain digits, try again!', 'Error','error');
            else
                %tjek om CPR-nummeret har det rigtige antal digits
                lengthOfCPR = length(app.CPREditField.Value);
                if lengthOfCPR < 10 || lengthOfCPR > 10
                    msgbox('Invalid CPR-number - It should consist of precisely 10 digits, try again!', 'Error','error');
                elseif lengthOfCPR==10
                    %Sæt det indtastede CPR-nummer til currentCPR
                    app.CurrentCPR = app.CPREditField.Value;
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.MonitorApp = mainapp;
            app.DataToSave = data;
            
            % Test af at modtage data
            %app.XData.Text = app.DataToSave;
        end

        % Button pushed function: AnnullerButton
        function AnnullerButtonPushed(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: GemButton
        function GemButtonPushed(app, event)
            % Test for Kode til opdatering
            %Test_update(app.MonitorApp, app.CPREditField.Value);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
           
            %Når der trykkes Gem skal der tjekkes om et CPR-nummer er
            %indtastet (hvis ikke, skal der komme en fejlmeddelelse om at
            %personalet skal indtaste og trykke på OK.
            
            if isempty(app.CurrentCPR) == 0 %Isempty returnerer 0 hvis CPR ikke er tom
                %Tag screenshot
                img = takeScreenDump(); %metode ligger i scriptet
                
                %Generatefilename
                new_currentDate = strrep(app.Date,'/','-');
                new_currentTime = strrep(app.Time,':','.');
                formatSpec = 'CPR%s_Date%s_EndTime%s.png';
                file_name = sprintf(formatSpec,app.CurrentCPR,new_currentDate,new_currentTime);
                
                %Save image
                imwrite(img,file_name) %unikt navn med CPR, dato og sluttid
                
                %Messagedialog
                msgbox('The measurements were succesfully saved!','Success');
                
                %Vinduet skal lukkes og returneres til startsiden
                delete(app)
            else
                msgbox('The patients CPR-number must be registered before saving the measurements. Please register the CPR-number and try again!', 'Error','error');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %%%%%Kun til test%%%%%%%
            app.PresentData();
        end

        % Button pushed function: CPROKButton
        function CPROKButtonPushed(app, event)
            %Tjek CPR
            app.ValidateCPR();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesHR = uiaxes(app.UIFigure);
            title(app.UIAxesHR, 'Puls')
            xlabel(app.UIAxesHR, 'Tid')
            ylabel(app.UIAxesHR, 'BPM')
            app.UIAxesSPO2 = uiaxes(app.UIFigure);
            title(app.UIAxesSPO2, 'Iltmætning')
            xlabel(app.UIAxesSPO2, 'Tid')
            ylabel(app.UIAxesSPO2, '%')
            app.UIAxesTemp = uiaxes(app.UIFigure);
            title(app.UIAxesTemp, 'Temperatur')
            xlabel(app.UIAxesTemp, 'Tid')
            ylabel(app.UIAxesTemp, '*C')
            app.AlarmeringerPanel = uipanel(app.UIFigure);
            app.AlarmeringerPanel.Title = 'Alarmeringer';
            app.DatoogtidPanel = uipanel(app.UIFigure);
            app.DatoogtidPanel.Title = 'Dato og tid';
            app.TidEditFieldLabel = uilabel(app.DatoogtidPanel);
            app.TidEditFieldLabel.Text = 'Tid:';
            app.TidEditField = uieditfield(app.DatoogtidPanel, 'text');
            app.TidEditField.Enable = 'off';
            app.DatoEditFieldLabel = uilabel(app.DatoogtidPanel);
            app.DatoEditFieldLabel.Text = 'Dato:';
            app.DatoEditField = uieditfield(app.DatoogtidPanel, 'text');
            app.DatoEditField.Enable = 'off';
            app.IndtastpatientensCPRnummerogtrykOKPanel = uipanel(app.UIFigure);
            app.IndtastpatientensCPRnummerogtrykOKPanel.Title = 'Indtast patientens CPR-nummer og tryk OK';
            app.xxxxxxxxxxLabel = uilabel(app.IndtastpatientensCPRnummerogtrykOKPanel);
            app.xxxxxxxxxxLabel.Text = '(xxxxxxxxxx)';
            app.CPROKButton = uibutton(app.IndtastpatientensCPRnummerogtrykOKPanel, 'push');
            app.CPROKButton.ButtonPushedFcn = createCallbackFcn(app, @CPROKButtonPushed, true);
            app.CPROKButton.Text = 'OK';
            app.CPRLabel = uilabel(app.IndtastpatientensCPRnummerogtrykOKPanel);
            app.CPRLabel.Text = 'CPR:';
            app.CPREditField = uieditfield(app.IndtastpatientensCPRnummerogtrykOKPanel, 'text');
            app.GemAnnullerPanel = uipanel(app.UIFigure);
            app.GemAnnullerPanel.Title = 'Gem/Annuller';
            app.GemButton = uibutton(app.GemAnnullerPanel, 'push');
            app.GemButton.ButtonPushedFcn = createCallbackFcn(app, @GemButtonPushed, true);
            app.GemButton.Text = 'Gem';
            app.AnnullerButton = uibutton(app.GemAnnullerPanel, 'push');
            app.AnnullerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnullerButtonPushed, true);
            app.AnnullerButton.Text = 'Annuller';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end